classdef DataPreparation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DataPreparationPanel           matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        StatusPanelLabel               matlab.ui.control.Label
        ClearButton_2                  matlab.ui.control.Button
        StatusTextArea                 matlab.ui.control.TextArea
        DataSettingsPanel              matlab.ui.container.Panel
        SaveBitButtonGroup             matlab.ui.container.ButtonGroup
        bit8                           matlab.ui.control.RadioButton
        bit16                          matlab.ui.control.RadioButton
        bit32                          matlab.ui.control.RadioButton
        SaveSubstacksCheckBox          matlab.ui.control.CheckBox
        RunButton                      matlab.ui.control.Button
        PropertiesPanel                matlab.ui.container.Panel
        StepSizeEditField              matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel         matlab.ui.control.Label
        mLabel_5                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        PixelSizeEditField             matlab.ui.control.NumericEditField
        PixelSizeLabel                 matlab.ui.control.Label
        DatasourceButtonGroup          matlab.ui.container.ButtonGroup
        CustomizedButton               matlab.ui.control.RadioButton
        ExampleDataButton              matlab.ui.control.RadioButton
        DataLabelEditField             matlab.ui.control.EditField
        DataLabelEditFieldLabel        matlab.ui.control.Label
        DataPathTextArea               matlab.ui.control.TextArea
        DataPathLabel                  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        AdvancedTab                    matlab.ui.container.Tab
        CustomizedPSFPanel             matlab.ui.container.Panel
        LoadingPSF                     matlab.ui.control.Button
        psf_Text_pannel                matlab.ui.control.TextArea
        PSFpathLabel                   matlab.ui.control.Label
        ProjectionSettingsPanel        matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SASTimesEditFieldLabel         matlab.ui.control.Label
        scan25                         matlab.ui.control.RadioButton
        scan9                          matlab.ui.control.RadioButton
        ProjectionGPUEditField         matlab.ui.control.NumericEditField
        ProjectionGPUEditFieldLabel    matlab.ui.control.Label
        PreEnhancedDataPanel           matlab.ui.container.Panel
        DatapathLabel                  matlab.ui.control.Label
        LoadingEnhancedData            matlab.ui.control.Button
        EnhancedDataPath               matlab.ui.control.TextArea
        CropSettingsPanel              matlab.ui.container.Panel
        ThreshholdEditField            matlab.ui.control.NumericEditField
        ThreshholdEditFieldLabel       matlab.ui.control.Label
        TraingPairSaveButtonGroup      matlab.ui.container.ButtonGroup
        TiFButton                      matlab.ui.control.RadioButton
        HDF5Button                     matlab.ui.control.RadioButton
        ReCropSubstacksButton          matlab.ui.control.Button
        n_parisEditField               matlab.ui.control.NumericEditField
        n_parisEditFieldLabel          matlab.ui.control.Label
        PatchSizeEditField             matlab.ui.control.NumericEditField
        PatchSizeEditFieldLabel        matlab.ui.control.Label
        DegradationSimuPanel           matlab.ui.container.Panel
        lambda_PossionEditField        matlab.ui.control.NumericEditField
        lambda_PossionEditFieldLabel   matlab.ui.control.Label
        times105Label                  matlab.ui.control.Label
        GaussionNoise_maxEditField     matlab.ui.control.NumericEditField
        sigma_GaussionEditField        matlab.ui.control.NumericEditField
        GaussionNoiseLabel             matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        BackgroundinLFMEditField       matlab.ui.control.NumericEditField
        BackgroundinLFMEditFieldLabel  matlab.ui.control.Label
        RollingBallRadiusEditField     matlab.ui.control.NumericEditField
        RollingBallRadiusEditFieldLabel  matlab.ui.control.Label
        RawBackgroundin3DEditField     matlab.ui.control.NumericEditField
        RawBackgroundin3DEditFieldLabel  matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        SBRmaxEditField                matlab.ui.control.NumericEditField
        SBRRangeEditField              matlab.ui.control.NumericEditField
        SBRRangeEditFieldLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        Text_str
    end

    methods (Access = public)

        function  outPut_str(app,str)
            print_str = sprintf('%s\n', str);
            app.Text_str=horzcat(app.Text_str,print_str);
            app.StatusTextArea.Value = app.Text_str;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Data Preparation';
            addpath ./source/manual_source/
            addpath ./source/psf/
            addpath ./utils/
            addpath ./DL/

            %% add initialization
            global img_structure;
            global psf_structure;
            global projection_structure

            img_structure=struct();
            img_structure.default_example_path='./example/lysosome/raw_data/';
            img_structure.enhanced_sr_sampling_ratio = [1,3.2];   % fixed the sampling rate for isotropic 3D data

            projection_structure=struct();
            projection_structure.WF_Zstep=0.17 ;    % fixed Zstep for light-field projection (PSF: 0.17)
            projection_structure.sr_factor=2;       % 120nm required sr factor (base: ~108nm)
            projection_structure.crop_thresh=0.5;   % Threshhold in training pairs cropping
            projection_structure.Z_shift_range=[-5,0,5];

            psf_structure.psf_customed_flag=0;
            psf_structure.path='None';
            psf_structure.default_path='./source/psf/PSFmatrix_M60NA1.1MLPitch100fml2800from-5.1to3.4zspacing0.85Nnum15lambda525n1.4.mat';

            app.Text_str{1}=sprintf('%s\n', '~>Initialization');
            app.StatusTextArea.Value=app.Text_str{1};
            outPut_str(app,'~>[Example Data] activated')

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global img_structure; %#ok<*GVMIS>
            [raw_data_name,raw_data_path] = uigetfile('*.tiff;*.tif','Select Input Raw Data','MultiSelect','on','./example');
            if ~ischar (raw_data_path) || ~exist(raw_data_path,'dir')
                f = errordlg('File not found','File Error');
                %                 pause(2)
                %                 close(f)
                error('no file');
            end
            if ~iscell(raw_data_name)
                raw_data_name={raw_data_name};
            end
            app.DataPathTextArea.Value=raw_data_path;
            img_structure.raw_data_path=raw_data_path;
            img_structure.raw_data_name=raw_data_name;
            outPut_str(app,sprintf('~>loading %d images',length(raw_data_name)))

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            global img_structure;
            global psf_structure;
            global projection_structure;
            addpath ./utils
            addpath ./utils/jsonlab-2.0/

            %%
            error_msg={};

            if app.PixelSizeEditField.Value<=0
                error_msg{end+1}='Requried: pixel size>0';
            end
            if app.StepSizeEditField.Value<=0
                error_msg{end+1}='Requried: step size >0';
            end
%             if abs(app.PSF_DepthRange_EditField.Value)+abs(app.PSF_max_depth_EditField.Value)==0
%                 error_msg{end+1}='Requried: depth range >0';
%             end
            if (app.CustomizedButton.Value) && ~exist(img_structure.raw_data_path,'dir')  
                error_msg{end+1}='Requried: correct raw data path';
            end
%           shift_vars= split(app.ShiftZRangeEditField.Value);
%           if isempty(shift_vars)
%             error_msg{end+1}='Requried: at least one Zshift (e.g."0")';
%           end
%           if app.SASTimesEditField.Value==0 || mod(sqrt(app.SASTimesEditField.Value),1)~=0
%              error_msg{end+1}='Requried:Sub-aperture shfit times >0 (e.g. 9)';
%           end
            if app.BackgroundinLFMEditField.Value<0
                error_msg{end+1}='Requried:offset >0';
            end

            if app.SBRRangeEditField.Value<=0
                error_msg{end+1}='Requried:SBR >0';
            end

            if app.lambda_PossionEditField.Value<0
                error_msg{end+1}='Requried:Possion Noise peak>=0';
            end
            if app.sigma_GaussionEditField.Value<0 ||app.GaussionNoise_maxEditField.Value<0
                error_msg{end+1}='Requried:Gaussion Noise peak>=0';
            end

            if ~isfile(psf_structure.path)  &&  psf_structure.psf_customed_flag==1
                error_msg{end+1}='Requried:PSF datamatrix (".mat")';
            end

            if ~isempty(error_msg)
                errordlg(error_msg);
            else
                %% Start projection
                gpu_idx= app.ProjectionGPUEditField.Value+1;
                spmd
                    gpuDevice(gpu_idx);
                end


                %% update PSF_STRUCTURE
                if app.ExampleDataButton.Value
                    psf_path= psf_structure.default_path;
                end
                if app.CustomizedButton.Value
                    psf_path= psf_structure.path;
                    if ~exist(psf_path,'file')
                        message = {sprintf('No PSF matrix found %s',psf_path)};
                        errordlg(message);
                        return
                        %                         error(sprintf('No PSF matrix found %s',psf_path));
                    end
                    psf_path=strrep(psf_path,'\','/');
                end
                psf_name=split(psf_path,'/');
                psf_name=psf_name{end};
%                 app.PSFPathTextArea.Value=sprintf('loading %s',psf_name);
                num_cell = regexp(psf_name,'\d*\.?\d*','match');
                assert(length(num_cell)==11);
%                 app.MagnificationEditField.Value=str2double(num_cell(1));
%                 app.MLAPitchEditField.Value=str2double(num_cell(3));
%                 app.NAEditField.Value=str2double(num_cell(2));
%                 app.MLAFocallengthEditField.Value=str2double(num_cell(4));
%                 app.NnumEditField.Value=str2double(num_cell(8));
%                 app.WavelengthEditField.Value=str2double(num_cell(9));
%                 app.nEditField.Value=str2double(num_cell(10));
%                 app.PSF_StepSizeEditField.Value=str2double(num_cell(7));
%                 app.PSF_DepthRange_EditField.Value=-str2double(num_cell(5));
%                 app.PSF_max_depth_EditField.Value=str2double(num_cell(6));

                psf_structure.path=psf_path;
                psf_structure.Magnification=str2double(num_cell(1));
                psf_structure.MLA_pitch=str2double(num_cell(3));
                psf_structure.NA=str2double(num_cell(2));
                psf_structure.focal_length=str2double(num_cell(4));
                psf_structure.Nnum=str2double(num_cell(8));
                psf_structure.wavelength=str2double(num_cell(9));
                psf_structure.n_imm=str2double(num_cell(10));
                psf_structure.Z_step=str2double(num_cell(7));
                psf_structure.Desied_depth=str2double(num_cell(6))+str2double(num_cell(5));

                %% generate dir
                prefix_label= app.DataLabelEditField.Value;
                prefix_root_dir=sprintf('./Data/%s',prefix_label);
                Sub_dir=sprintf('./Data/%s/S00_Multi_stage_data/',prefix_label);
                Training_data_dir=sprintf('./Data/%s/S01_TrainingData',prefix_label);

                if ~exist(Sub_dir,'dir')
                    mkdir(Sub_dir);
                end
                if ~exist(Training_data_dir,'dir')
                    mkdir(Training_data_dir);
                end
                save_substacks= app.SaveSubstacksCheckBox.Value;
                if save_substacks
                    [~,~]=mkdir(sprintf('%s/NoisyLF',Sub_dir));
                    [~,~]=mkdir(sprintf('%s/CleanLF',Sub_dir));
                    [~,~]=mkdir(sprintf('%s/DALF',Sub_dir));
                    [~,~]=mkdir(sprintf('%s/Substacks',Sub_dir));
                end
                %%  Step1: loading img
                %% loading the raw images
                if ~isfield(img_structure,'raw_data_name') && app.ExampleDataButton.Value
                    raw_path=img_structure.default_example_path;
                    raw_data_name=dir(sprintf('%s/*.tif',raw_path));
                    raw_data_name= struct2cell(raw_data_name);
                    raw_data_name= raw_data_name(1,:);
                    img_structure.raw_data_path=raw_path;
                    img_structure.raw_data_name=raw_data_name;
                    outPut_str(app,sprintf('~>[Example data detected] loading %d raw images',length(raw_data_name)))
                end

                imgs_num= length(img_structure.raw_data_name);
                step1_wab=waitbar(0,'Step1: Loading images');
                images_pool={};
                bit_depth = [];
                for ii=1:imgs_num
                    file_name=fullfile(img_structure.raw_data_path,img_structure.raw_data_name{ii});
%                     fprintf(file_name);
                    [imageStack,BitDepth]=imread3d(file_name);
                    images_pool{end+1}=single(imageStack);
                    bit_depth(end+1)=BitDepth;
                    waitbar(ii/imgs_num,step1_wab)
                end
                assert (length(bit_depth)==length(images_pool));
                close(step1_wab)

                %% loading the enhanced images
                % TODO check the bitdepth of enhanced data  and check
                % whether the size is compatiable with raw data's
                is_enhanced =false;
                images_enhanced_pool={};
                if isfield(img_structure,'enhanced_data_path')
                    enhanced_imgs_num= length(img_structure.enhanced_data_name);
                    assert(enhanced_imgs_num==imgs_num,'The num of enhanced data must be the same num of the rwa data')
                    step1_1_wab=waitbar(0,'Step1-1: Loading ehanced images');
                    for ii=1:enhanced_imgs_num
                        file_name=fullfile(img_structure.enhanced_data_path,img_structure.enhanced_data_name{ii});
                        [imageStack,~]=imread3d(file_name);
                        %% check size
                        images_enhanced_pool{end+1}=single(imageStack);
                        waitbar(ii/imgs_num,step1_1_wab)
                    end
                    is_enhanced=true;
                    close(step1_1_wab)
                end

                %%  Step2: loading psf
                step2_wab=waitbar(0,'Step2: Loading PSF');
                [LFpsf,~,~,psf_depth,Nnum,~] = read_psf(psf_structure.path);
                waitbar(1,step2_wab)
                pause(1)
                close(step2_wab)

                %% rescale//rectify//projection//save
                sr_ratio=projection_structure.sr_factor;
                rescale_LF_factor= app.PixelSizeEditField.Value/(psf_structure.MLA_pitch/psf_structure.Magnification/Nnum);
                rescale_SR_factor= rescale_LF_factor*sr_ratio;
                %                 z_scale_factor = app.StepSizeEditField.Value/app.reconStepSizeEditField.Value;
                z_scale_factor =1 ;
                if app.scan25.Value
                    shift_times=5;
                else
                    shift_times=3;
                end
                z_shift_range=projection_structure.Z_shift_range;
                assert(Nnum==psf_structure.Nnum);

                %% enhanced resize settings
                %                 recon_n_slices = psf_structure.Desied_depth/psf_structure.reconStep+1;
                assert(mod(psf_structure.Desied_depth,projection_structure.WF_Zstep)==0,'The depth range must be divisible by the z-step (0.17)');
                recon_n_slices = psf_structure.Desied_depth/projection_structure.WF_Zstep+1;
                recon_n_slices = int16(recon_n_slices);
                if is_enhanced
                    axial_ratio=img_structure.enhanced_sr_sampling_ratio(2);
                    enhanced_n_slices = axial_ratio*(recon_n_slices-1)+1;
                    enhanced_n_slices = int16(enhanced_n_slices);
                else
                    enhanced_n_slices=recon_n_slices;
                end
                size_print=sprintf('~>PSF step: %.2f PSF range:%.2f~%.2f; N_slices(no Enhanced,step0.17): %d, N_slices(axial Enhanced): %d', ...
                    psf_structure.Z_step, ...
                    -str2double(num_cell(5)), ...
                    str2double(num_cell(6)), ...
                    recon_n_slices, ...
                    enhanced_n_slices);
                outPut_str(app,size_print);
                %% Step 3 projection and crop
                step3_wab=waitbar(0,'Step3: Sub-aperture shift projection && Crop');
                hdf5_save_dir=fullfile(Training_data_dir,'training_data.h5');
                if app.bit8.Value
                    save_bitdepth = 8;
                    save_max=255;
                    save_min=0;
                elseif app.bit16.Value
                    save_bitdepth = 16;
                    save_max=65535;
                    save_min=0;
                elseif app.bit32.Value
                    save_bitdepth = 32;
                    save_max=1.0;
                    save_min=0;
                end
                patch_size= app.PatchSizeEditField.Value;
                totol_number=app.n_parisEditField.Value;
                temp_num=floor(totol_number/(imgs_num*length(z_shift_range)));
                n_paris_collect = ones(imgs_num*length(z_shift_range),1).*temp_num;
                last_numbers=totol_number-sum(n_paris_collect);
                n_paris_collect(end,1)=n_paris_collect(end,1)+last_numbers;
                srView_sride=patch_size*shift_times;
                Vol_stride= patch_size*sr_ratio;
                mean_threshold = app.ThreshholdEditField.Value;
                bg_value_for_LFM= app.RawBackgroundin3DEditField.Value;

                %% check memeoty
                %                 max_memeory = memory().MaxPossibleArrayBytes;
                %                 bit_training_data= 32;
                %                 budget_memo= max_memeory * 0.8;
                %                 %             budget_number_pairs=  (bit_training_data/8);
                %% json information
                %                 img_structure.bit_depths = bit_depth;
                img_structure.prefix=prefix_label;
                img_structure.vol_sr_factor=sr_ratio;
                img_structure.shift_times=shift_times;
                img_structure.properties = app.PixelSizeEditField.Value;
                img_structure.stepSize = app.StepSizeEditField.Value;
                img_structure.save_hdf5 = app.HDF5Button.Value;
                img_structure.Training_data_dir = Training_data_dir;
                img_structure.rolling_ball_radius = app.RollingBallRadiusEditField.Value;
                img_structure.z_shift_times = length(z_shift_range);
                img_structure.patch_size = patch_size;
                img_structure.save_bit=save_bitdepth;

                psf_structure.psf_depth= psf_depth;
                psf_structure.recon_n_slices= recon_n_slices;
                psf_structure.enhanced_n_slices= enhanced_n_slices;

                json_save_name= fullfile(prefix_root_dir,'Data_details.json');
                if exist(json_save_name,'file')
                    delete(json_save_name);
                end
                savejson('',img_structure,'filename',json_save_name,'Append',0);
                savejson('',psf_structure,'filename',json_save_name,'Append',1);


                %% generate the data structure
                if app.HDF5Button.Value
                    if ~exist(hdf5_save_dir,'file')
                        h5create(hdf5_save_dir,"/nLF",[totol_number patch_size patch_size],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/cLF",[totol_number patch_size patch_size],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/sLF",[totol_number srView_sride srView_sride],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/sVol",[totol_number Vol_stride Vol_stride double(enhanced_n_slices)],'Datatype', 'single');
                    else
                        delete(hdf5_save_dir);
                        h5create(hdf5_save_dir,"/nLF",[totol_number patch_size patch_size],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/cLF",[totol_number patch_size patch_size],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/sLF",[totol_number srView_sride srView_sride],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/sVol",[totol_number Vol_stride Vol_stride double(enhanced_n_slices)],'Datatype', 'single');
                    end
                    save_mat1= zeros([totol_number patch_size patch_size],'single');
                    save_mat2= zeros([totol_number patch_size patch_size],'single');
                    save_mat3= zeros([totol_number srView_sride srView_sride],'single');
                    save_mat4= zeros([totol_number Vol_stride Vol_stride enhanced_n_slices],'single');
                else
                    [~,~]=mkdir(sprintf('%s/nLF',Training_data_dir));
                    [~,~]=mkdir(sprintf('%s/cLF',Training_data_dir));
                    [~,~]=mkdir(sprintf('%s/sLF',Training_data_dir));
                    [~,~]=mkdir(sprintf('%s/sVol',Training_data_dir));
                end
                %% main func
                count_idx=0;
                offset = 0;
                outPut_str(app,sprintf('~>Projection offset:%d',offset));
                if ~is_enhanced
                    for img_idx=1+offset:imgs_num
                        temp_img=images_pool{img_idx};
%                         local_bitdpeth =bit_depth(img_idx);

                        %% rescale
                        temp_size=ceil(size(temp_img).*[rescale_SR_factor,rescale_SR_factor,z_scale_factor]);
                        temp_img= imresize3(temp_img,temp_size,'cubic');
                        %% cut zero and rolling ball
                        temp_img= temp_img-bg_value_for_LFM;
                        temp_img(temp_img<0)=0;

                        if app.RollingBallRadiusEditField.Value~=0
                            %% ("disk" open )
                            background=rolling_ball_Morpho(temp_img,app.RollingBallRadiusEditField.Value);
                            sb_imgs=temp_img-background;
                            sb_imgs(sb_imgs<0)=0;
                            %                         outPut_str(app,sprintf('~>[rolling ball] time elapsed %f seconds',ttime))
                        else
                            sb_imgs=temp_img;
                        end

                        %% rectify && padding
                        temp_img= Rectify_imgs(temp_img,Nnum,sr_ratio,recon_n_slices);
                        sb_imgs= Rectify_imgs(sb_imgs,Nnum,sr_ratio,recon_n_slices);
                        %% Padding lateral
                        temp_img = Padding_Lateral(temp_img,Vol_stride);
                        sb_imgs = Padding_Lateral(sb_imgs,Vol_stride);
                        %% get pair's boundary coordi by mean-threshold
                        %                     tic;
                        coordi_pool= get_coordi(sb_imgs,Vol_stride,mean_threshold,Nnum,sr_ratio);
                        %                     ttime = toc;
                        %                     outPut_str(app,sprintf('~>[coordinates of blocks search] time elapsed %f seconds',ttime))
                        assert(~isempty(coordi_pool));
                        wf_size= floor([0.5*size(temp_img,1),0.5*size(temp_img,2),psf_depth]);
                        assert(~mod(wf_size(1),Nnum));
                        for delta_z_idx =1:length(z_shift_range)

                            SR_vol= imtranslate(sb_imgs,[0,0,z_shift_range(delta_z_idx)]);  %% SR Volume
                            clean_wf = imresize3(SR_vol,wf_size,'cubic');
                            clean_wf (clean_wf<0)=0;
                            %% clean LFP
                            tic;
                            [srLF,centerLF]=SAS_LFP(clean_wf,LFpsf,shift_times);
                            ttime = toc;
                            outPut_str(app,sprintf('~>[Sub-aperture Shif] time elapsed %f seconds',ttime))

                            %% noise  Volume Projection
                            if app.RollingBallRadiusEditField.Value~=0
                                WF_images = imtranslate(temp_img,[0,0,z_shift_range(delta_z_idx)]);
                                WF_images = imresize3(WF_images,wf_size,'cubic');
                                WF_images (WF_images<0)=0;
                                noise_LF= singleLFP(WF_images,LFpsf);
                            else
                                noise_LF= centerLF;
                            end
                            %% addnoise
                            dark_offset = app.BackgroundinLFMEditField.Value;
                            gau_range=[app.sigma_GaussionEditField.Value,app.GaussionNoise_maxEditField.Value]*1e-5;
                            sbr_range=[app.SBRRangeEditField.Value,app.SBRmaxEditField.Value];
                            if gau_range(2)~=0
                                %% add gaussion noise
                                noise_LF = noise_LF/max(noise_LF(:));
                                %                             sample_std= normrnd(gau_range(1),gau_range(2));
                                sample_var=random('Uniform',gau_range(1),gau_range(2));
                                sample_sbr= (sbr_range(2)-sbr_range(1))*rand(1)+sbr_range(1);
                                %                             noise_LF = imnoise(noise_LF,'gaussian',0,sample_std);
                                sample_noise = random('Normal',0,sqrt(sample_var),wf_size(1),wf_size(2));
                                noise_LF = noise_LF+sample_noise;
                                %% add possion noise
                                %
                                %
                                %
                                %% add offset
                                noise_LF = (noise_LF-min(noise_LF(:)))/(max(noise_LF(:))-min(noise_LF(:)));
                                noise_LF = (noise_LF*sample_sbr+1)*dark_offset;
                            else
                                noise_LF = (noise_LF-min(noise_LF(:)))/(max(noise_LF(:))-min(noise_LF(:)));
                                noise_LF = (noise_LF*sample_sbr+1)*dark_offset;
                            end

                            %% crop
                            n_pairs_per_image=n_paris_collect(delta_z_idx+(img_idx-1)*length(z_shift_range),1);
                            if length(coordi_pool)<=n_pairs_per_image
                                random_idx1= randperm(length(coordi_pool));
                                random_idx2= randi([1,length(coordi_pool)],n_pairs_per_image-length(coordi_pool),1);
                                random_idx = [random_idx1';random_idx2];
                            else
                                random_idx= randperm(length(coordi_pool));
                                random_idx = random_idx(1:n_pairs_per_image);
                            end
                            select_coordi = coordi_pool(random_idx,:);

                            for coordi_idx=1:size(select_coordi,1)
                                %% roi

                                Vol_h_s=select_coordi(coordi_idx,1);
                                Vol_h_e=select_coordi(coordi_idx,2);

                                Vol_w_s = select_coordi(coordi_idx,3);
                                Vol_w_e = select_coordi(coordi_idx,4);


                                h_e= Vol_h_e/sr_ratio;
                                h_s= h_e-patch_size;

                                w_e= Vol_w_e/sr_ratio;
                                w_s= w_e-patch_size;

                                sv_h_e=h_e*shift_times;
                                sv_h_s= sv_h_e-srView_sride+1;

                                sv_w_e= w_e*shift_times;
                                sv_w_s= sv_w_e-srView_sride+1;

                                count_idx = count_idx+1;
                                c_x0= noise_LF(  h_s+1:h_e,w_s+1:w_e );
                                c_x1= centerLF(  h_s+1:h_e,w_s+1:w_e);
                                c_x2= srLF(  sv_h_s:sv_h_e,sv_w_s:sv_w_e);
                                c_x3= SR_vol(  Vol_h_s:Vol_h_e,Vol_w_s:Vol_w_e,:);

                                %% convet formats (LFM: to Macron; LFP: to ViewMap; Scan: to ViewMap)
                                c_x1= LFP2Map(c_x1,Nnum);
                                c_x2= LFP2Map(c_x2,Nnum);

                                if app.HDF5Button.Value
                                    save_mat1(count_idx,:,:)=single(normalize(c_x0,0,100));
                                    save_mat2(count_idx,:,:)=single(normalize(c_x1,0,100));
                                    save_mat3(count_idx,:,:)=single(normalize(c_x2,0,100));
                                    save_mat4(count_idx,:,:,:)=single(normalize(c_x3,0,100));
                                else
                                    noise_lf_str=sprintf('%s/nLF/Idx_%05d.tif',Training_data_dir,count_idx);
                                    clean_lf_str=sprintf('%s/cLF/Idx_%05d.tif',Training_data_dir,count_idx);
                                    scan_lf_str=sprintf('%s/sLF/Idx_%05d.tif',Training_data_dir,count_idx);
                                    sr_stacks_str=sprintf('%s/sVol/Idx_%05d.tif',Training_data_dir,count_idx);
%                                     if pre_normalization
                                    write3d(single(normalize(c_x0,0,100))*save_max,noise_lf_str,save_bitdepth);
                                    write3d(single(normalize(c_x1,0,100))*save_max,clean_lf_str,save_bitdepth);
                                    write3d(single(normalize(c_x2,0,100))*save_max,scan_lf_str,save_bitdepth);
                                    write3d(single(normalize(c_x3,0,100))*save_max,sr_stacks_str,save_bitdepth);
%                                         write3d(c_x0,noise_lf_str,local_bitdpeth);
%                                         write3d(c_x1,clean_lf_str,local_bitdpeth);
%                                         write3d(c_x2,scan_lf_str,local_bitdpeth);
%                                         write3d(c_x3,sr_stacks_str,local_bitdpeth);

                                end

                                msg=sprintf('SAS Projection (idx:%d/%d --z:%d/%d),Cropping: (idx:%d/%d)',img_idx,imgs_num,delta_z_idx,length(z_shift_range),coordi_idx,n_pairs_per_image);
                                waitbar(count_idx/totol_number,step3_wab,msg);

                            end
                            %                        app.BitDepthEditField.Value=32;
                            if save_substacks
                                save_name=sprintf('Idx_%04d_%d.tif',img_idx,z_shift_range(delta_z_idx));
                                noise_lf_str=fullfile(sprintf('%s/NoisyLF',Sub_dir),save_name);
                                clean_lf_str=fullfile(sprintf('%s/CleanLF',Sub_dir),save_name);
                                scan_lf_str=fullfile(sprintf('%s/DALF',Sub_dir),save_name);
                                sr_stacks_str=fullfile(sprintf('%s/Substacks',Sub_dir),save_name);


                                write3d(single(normalize(noise_LF,0,100))*save_max,noise_lf_str,save_bitdepth);
                                write3d(single(normalize(centerLF,0,100))*save_max,clean_lf_str,save_bitdepth);
                                write3d(single(normalize(srLF,0,100))*save_max,scan_lf_str,save_bitdepth);
                                write3d(single(normalize(SR_vol,0,100))*save_max,sr_stacks_str,save_bitdepth);

%                                 write3d(noise_LF,noise_lf_str,32);
%                                 write3d(centerLF,clean_lf_str,32);
%                                 write3d(srLF,scan_lf_str,32);
%                                 write3d(SR_vol,sr_stacks_str,32);
                            end
                        end
                    end
                else
                    for img_idx=1+offset:imgs_num

                        temp_img=images_pool{img_idx};   %% for Noisy LF generation
                        enhanced_img= images_enhanced_pool{img_idx};

%                         local_bitdpeth =bit_depth(img_idx);

                        %% rescale (SR data: raw && enhanced data rescaled to the sr sampling rate)
                        temp_size=ceil(size(temp_img).*[rescale_SR_factor,rescale_SR_factor,z_scale_factor]);
                        temp_img= imresize3(temp_img,temp_size,'cubic');
                        enhanced_sr_size=ceil(size(enhanced_img).*[rescale_SR_factor,rescale_SR_factor,1]);
                        enhanced_img= imresize3(enhanced_img,enhanced_sr_size,'cubic');
                        %% cut zero 
                        temp_img= temp_img-bg_value_for_LFM;
                        temp_img(temp_img<0)=0;
                        %% rectify && padding  (size: Nnum*Nx*sr_ratio, where Nx is odd for SAS projection)
                        temp_img= Rectify_imgs(temp_img,Nnum,sr_ratio,recon_n_slices);
                        sb_imgs= Rectify_imgs(enhanced_img,Nnum,sr_ratio,enhanced_n_slices);

                        %% Padding lateral
                        temp_img = Padding_Lateral(temp_img,Vol_stride);
                        sb_imgs = Padding_Lateral(sb_imgs,Vol_stride);

                        %% get pair's boundary coordi by mean-threshold
                        %                     tic;
                        coordi_pool= get_coordi(sb_imgs,Vol_stride,mean_threshold,Nnum,sr_ratio);
                        %                     ttime = toc;
                        %                     outPut_str(app,sprintf('~>[coordinates of blocks search] time elapsed %f seconds',ttime))
                        assert(~isempty(coordi_pool));

                        wf_size= floor([size(temp_img,1)/sr_ratio,size(temp_img,2)/sr_ratio,psf_depth]);
                        assert(~mod(wf_size(1),Nnum));

                        for delta_z_idx =1:length(z_shift_range)

                            ori_shift_value=z_shift_range(delta_z_idx);
                            enhanced_shift_value= ori_shift_value*axial_ratio;
                            
                            SR_vol= imtranslate(sb_imgs,[0,0,enhanced_shift_value]);  %% SR Volume

                            clean_wf = imresize3(SR_vol,wf_size,'cubic');
                            clean_wf (clean_wf<0)=0;
                            %% clean LFP
                            tic;
                            [srLF,centerLF]=SAS_LFP(clean_wf,LFpsf,shift_times);
                            ttime = toc;
                            outPut_str(app,sprintf('~>[Sub-aperture Shif] time elapsed %f seconds',ttime))

                            %% noise  Volume Projection
                            if app.RollingBallRadiusEditField.Value~=0
                                WF_images = imtranslate(temp_img,[0,0,z_shift_range(delta_z_idx)]);
                                WF_images = imresize3(WF_images,wf_size,'cubic');
                                WF_images (WF_images<0)=0;
                                noise_LF= singleLFP(WF_images,LFpsf);
                            else
                                noise_LF= centerLF;
                            end
                            %% addnoise
                            dark_offset = app.BackgroundinLFMEditField.Value;
                            gau_range=[app.sigma_GaussionEditField.Value,app.GaussionNoise_maxEditField.Value]*1e-5;
                            sbr_range=[app.SBRRangeEditField.Value,app.SBRmaxEditField.Value];
                            if gau_range(2)~=0
                                %% add gaussion noise
                                noise_LF = noise_LF/max(noise_LF(:));
                                %                             sample_std= normrnd(gau_range(1),gau_range(2));
                                sample_var=random('Uniform',gau_range(1),gau_range(2));
                                sample_sbr= (sbr_range(2)-sbr_range(1))*rand(1)+sbr_range(1);
                                %                             noise_LF = imnoise(noise_LF,'gaussian',0,sample_std);
                                sample_noise = random('Normal',0,sqrt(sample_var),wf_size(1),wf_size(2));
                                noise_LF = noise_LF+sample_noise;
                                %% add possion noise
                                %
                                %
                                %
                                %% add offset
                                noise_LF = (noise_LF-min(noise_LF(:)))/(max(noise_LF(:))-min(noise_LF(:)));
                                noise_LF = (noise_LF*sample_sbr+1)*dark_offset;
                            else
                                noise_LF = (noise_LF-min(noise_LF(:)))/(max(noise_LF(:))-min(noise_LF(:)));
                                noise_LF = (noise_LF*sample_sbr+1)*dark_offset;
                            end

                            %% crop
                            n_pairs_per_image=n_paris_collect(delta_z_idx+(img_idx-1)*length(z_shift_range),1);
                            if length(coordi_pool)<=n_pairs_per_image
                                random_idx1= randperm(length(coordi_pool));
                                random_idx2= randi([1,length(coordi_pool)],n_pairs_per_image-length(coordi_pool),1);
                                random_idx = [random_idx1';random_idx2];
                            else
                                random_idx= randperm(length(coordi_pool));
                                random_idx = random_idx(1:n_pairs_per_image);
                            end
                            select_coordi = coordi_pool(random_idx,:);

                            for coordi_idx=1:size(select_coordi,1)
                                %% roi

                                Vol_h_s=select_coordi(coordi_idx,1);
                                Vol_h_e=select_coordi(coordi_idx,2);

                                Vol_w_s = select_coordi(coordi_idx,3);
                                Vol_w_e = select_coordi(coordi_idx,4);


                                h_e= Vol_h_e/sr_ratio;
                                h_s= h_e-patch_size;

                                w_e= Vol_w_e/sr_ratio;
                                w_s= w_e-patch_size;

                                sv_h_e=h_e*shift_times;
                                sv_h_s= sv_h_e-srView_sride+1;

                                sv_w_e= w_e*shift_times;
                                sv_w_s= sv_w_e-srView_sride+1;

                                count_idx = count_idx+1;
                                c_x0= noise_LF(  h_s+1:h_e,w_s+1:w_e );
                                c_x1= centerLF(  h_s+1:h_e,w_s+1:w_e);
                                c_x2= srLF(  sv_h_s:sv_h_e,sv_w_s:sv_w_e);
                                c_x3= SR_vol(  Vol_h_s:Vol_h_e,Vol_w_s:Vol_w_e,:);

                                %% convet formats (LFM: to Macron; LFP: to ViewMap; Scan: to ViewMap)
                                c_x1= LFP2Map(c_x1,Nnum);
                                c_x2= LFP2Map(c_x2,Nnum);

                                if app.HDF5Button.Value
                                    save_mat1(count_idx,:,:)=single(normalize(c_x0,0,100));
                                    save_mat2(count_idx,:,:)=single(normalize(c_x1,0,100));
                                    save_mat3(count_idx,:,:)=single(normalize(c_x2,0,100));
                                    save_mat4(count_idx,:,:,:)=single(normalize(c_x3,0,100));
                                else
                                    noise_lf_str=sprintf('%s/nLF/Idx_%05d.tif',Training_data_dir,count_idx);
                                    clean_lf_str=sprintf('%s/cLF/Idx_%05d.tif',Training_data_dir,count_idx);
                                    scan_lf_str=sprintf('%s/sLF/Idx_%05d.tif',Training_data_dir,count_idx);
                                    sr_stacks_str=sprintf('%s/sVol/Idx_%05d.tif',Training_data_dir,count_idx);

                                    write3d(single(normalize(c_x0,0,100))*save_max,noise_lf_str,save_bitdepth);
                                    write3d(single(normalize(c_x1,0,100))*save_max,clean_lf_str,save_bitdepth);
                                    write3d(single(normalize(c_x2,0,100))*save_max,scan_lf_str,save_bitdepth);
                                    write3d(single(normalize(c_x3,0,100))*save_max,sr_stacks_str,save_bitdepth);
                                end

                                msg=sprintf('SAS Projection (idx:%d/%d --z:%d/%d),Cropping: (idx:%d/%d)',img_idx,imgs_num,delta_z_idx,length(z_shift_range),coordi_idx,n_pairs_per_image);
                                waitbar(count_idx/totol_number,step3_wab,msg);

                            end
                            %                        app.BitDepthEditField.Value=32;
                            if save_substacks
                                save_name=sprintf('Idx_%04d_%d.tif',img_idx,z_shift_range(delta_z_idx));
                                noise_lf_str=fullfile(sprintf('%s/NoisyLF',Sub_dir),save_name);
                                clean_lf_str=fullfile(sprintf('%s/CleanLF',Sub_dir),save_name);
                                scan_lf_str=fullfile(sprintf('%s/DALF',Sub_dir),save_name);
                                sr_stacks_str=fullfile(sprintf('%s/Substacks',Sub_dir),save_name);
                                
                                write3d(single(normalize(noise_LF,0,100))*save_max,noise_lf_str,save_bitdepth);
                                write3d(single(normalize(centerLF,0,100))*save_max,clean_lf_str,save_bitdepth);
                                write3d(single(normalize(srLF,0,100))*save_max,scan_lf_str,save_bitdepth);
                                write3d(single(normalize(SR_vol,0,100))*save_max,sr_stacks_str,save_bitdepth);
                            end
                        end
                    end
                end


                if app.HDF5Button.Value
                    msg =sprintf('Saving TrainingPairs');
                    waitbar(1,step3_wab,msg);
                    h5write(hdf5_save_dir,"/nLF",save_mat1);
                    h5write(hdf5_save_dir,"/cLF",save_mat2);
                    h5write(hdf5_save_dir,"/sLF",save_mat3);
                    h5write(hdf5_save_dir,"/sVol",save_mat4);
                    close(step3_wab);
                else
                    msg =sprintf('Projection Finished');
                    waitbar(1,step3_wab,msg);
                    close(step3_wab);
                end

            end

        end

        % Callback function
        function Button_2Pushed(app, event)
            global psf_structure
            [psf_name,psf_path] = uigetfile('*.mat;*.pth','Select PSF file','MultiSelect','on','./source/PSF');
            file=fullfile(psf_path,psf_name);
            if ~exist(file,'file')
                message = {sprintf('No PSF matrix found %s',psf_path)};
                errordlg(message);
                %                 PSFPathTextArea.Value='please input psf matrix or simulate directly';
                %                 file= './source/psf/AiryScan/PSFmatrix_M60NA1.1MLPitch100fml2800from-5.1to3.4zspacing0.85Nnum15lambda525n1.4.mat';
                %                 app.PSFPathTextArea.Value='loading default psf';
                %                 app.MagnificationEditField.Value=60;
                %                 app.MLAPitchEditField.Value=100;
                %                 app.NAEditField.Value=1.4;
                %                 app.MLAFocallengthEditField.Value=2800;
                %                 app.NnumEditField.Value=15;
                %                 app.WavelengthEditField.Value=525;
                %                 app.nEditField.Value=1.4;
                %                 app.PSF_StepSizeEditField.Value=0.17;
                %                 app.PSF_DepthRange_EditField.Value=-5.1;
                %                 app.PSF_max_depth_EditField.Value=3.4;
                %                 psf_structure.psf_customed_flag=0;
            else
                %             [LFpsf,height,width,depth,Nnum,CAindex] = read_psf(file);
                app.PSFPathTextArea.Value=sprintf('loading %s',psf_name);
                num_cell = regexp(psf_name,'\d*\.?\d*','match');
                assert(length(num_cell)==11);
                app.MagnificationEditField.Value=str2double(num_cell(1));
                app.MLAPitchEditField.Value=str2double(num_cell(3));
                app.NAEditField.Value=str2double(num_cell(2));
                app.MLAFocallengthEditField.Value=str2double(num_cell(4));
                app.NnumEditField.Value=str2double(num_cell(8));
                app.WavelengthEditField.Value=str2double(num_cell(9));
                app.nEditField.Value=str2double(num_cell(10));
                app.PSF_StepSizeEditField.Value=str2double(num_cell(7));
                app.PSF_DepthRange_EditField.Value=str2double(num_cell(5));
                app.PSF_max_depth_EditField.Value=str2double(num_cell(6));
                psf_structure.psf_customed_flag=1;
                app.ExampleDataButton.Value=1;
            end

            psf_structure.path=file;

            psf_structure.Magnification=app.MagnificationEditField.Value;
            psf_structure.MLA_pitch=app.MLAPitchEditField.Value;
            psf_structure.NA=app.NAEditField.Value;
            psf_structure.focal_length=app.MLAFocallengthEditField.Value;
            psf_structure.Nnum=app.NnumEditField.Value;
            psf_structure.wavelength=app.WavelengthEditField.Value;
            psf_structure.n_imm=app.nEditField.Value;
            psf_structure.Z_step=app.PSF_StepSizeEditField.Value;
            psf_structure.Desied_depth=app.PSF_DepthRange_EditField.Value;


            ;

        end

        % Callback function
        function PSFSimuButtonPushed(app, event)


            % TODO add check state function
            assert(app.PSF_DepthRange_EditField.Value~=0);
            assert(app.PSF_StepSizeEditField.Value~=0);


            % z_range=abs(app.PSF_DepthRange_EditField.Value/2)*[-1,1];
            z_step= app.PSF_StepSizeEditField.Value;

            min_z= floor(app.PSF_DepthRange_EditField.Value/z_step)*z_step;
            max_z= ceil(app.PSF_max_depth_EditField.Value/z_step)*z_step;

            psf_save_path='./source/psf/';
            global psf_structure
            psf_structure.Zrange = [min_z,max_z];
            psf_structure.Z_step = z_step;


            % update
            psf_structure.path='Save_to_default_psf_path';
            psf_structure.Magnification=app.MagnificationEditField.Value;
            psf_structure.MLA_pitch=app.MLAPitchEditField.Value;
            psf_structure.NA=app.NAEditField.Value;
            psf_structure.focal_length=app.MLAFocallengthEditField.Value;
            psf_structure.Nnum=app.NnumEditField.Value;
            psf_structure.wavelength=app.WavelengthEditField.Value;
            psf_structure.n_imm=app.nEditField.Value;


            %             simu_flag=0;
            %             cancel_stat=0;
            %             while simu_flag~=1
            hWaitBar = waitbar(0,'Simulation inprocess');
            %                 hCancelButton = findall(hWaitBar, 'style', 'pushbutton');
            %                 set(hCancelButton, 'string', '取消', 'fontsize', 8);
            %                 setappdata(hWaitBar, 'isCanceled', false);

            %                 f = msgbox(message);
            computePSF(psf_structure,psf_save_path,hWaitBar);
            close(hWaitBar);


        end

        % Selection changed function: DatasourceButtonGroup
        function DatasourceButtonGroupSelectionChanged(app, event)
            %             selectedButton = app.DatasourceButtonGroup.SelectedObject;
            %                 elseif app.ExampleDataButton.Value
            %                     data_type = 'AiryScan';
            %                     psf_path= './source/psf/AiryScan/PSFmatrix_M60NA1.1MLPitch100fml2800from-5.1to5.1zspacing0.51Nnum15lambda525n1.4.mat';
            %                     app.reconStepSizeEditField.Value=0.17;
            %                 elseif app.CustomizedButton.Value
            %                     data_type = '3D_sim';
            %                     psf_path= './source/psf/3D_sim/PSFmatrix_M60NA1.1MLPitch100fml2800from-5to5zspacing0.5Nnum15lambda525n1.4.mat';
            %                     app.reconStepSizeEditField.Value=0.25;
            %                 else
            %                     data_type = 'Confocal';
            %                     psf_path= './source/psf/AiryScan/PSFmatrix_M60NA1.1MLPitch100fml2800from-5.1to5.1zspacing0.51Nnum15lambda525n1.4.mat';
            %                     app.reconStepSizeEditField.Value=0.17;
            %                 end
            global img_structure;
            if app.ExampleDataButton.Value
%                 app.reconStepSizeEditField.Value=0.17;
                app.DataPathTextArea.Value=img_structure.default_example_path;
                app.RollingBallRadiusEditField.Value=10;
                app.PixelSizeEditField.Value=0.054;
                app.StepSizeEditField.Value=0.17;
                outPut_str(app,sprintf("~>[Example data] activated."))
            elseif app.CustomizedButton.Value
%                 app.reconStepSizeEditField.Value=0.25;
                app.RollingBallRadiusEditField.Value=10;
                app.PixelSizeEditField.Value=0.055;
                app.StepSizeEditField.Value=0.25;
                outPut_str(app,sprintf('~>[Customized data] activated. Please choose the path of new data in Advanced Panel'))
            end

        end

        % Button pushed function: ReCropSubstacksButton
        function ReCropSubstacksButtonPushed(app, event)

            addpath ./utils/
            addpath ./utils/jsonlab-2.0/
            global projection_structure
            sr_ratio=projection_structure.sr_factor;
            %% description: directly crop substacks

%             congfig_file_path = uigetdir(;'./Data','Select the Substack dir');
            [json_name,json_dir] = uigetfile('*.json;*.pth','Select the data config','MultiSelect','off','./Data');
            congfig_file_path= fullfile(json_dir,json_name);
            if ~exist(congfig_file_path,'file')
                errordlg("Can't find the data configs, please check");
                %                 error('');
            else

                img_settings = loadjson(congfig_file_path);
                psf_settings=img_settings{2};
                img_settings=img_settings{1};

                prefix = img_settings.prefix;
                Training_data_dir= img_settings.Training_data_dir;

                %%
                Training_data_dir = sprintf('%s_p240',Training_data_dir);
                %%
                %                 imgs_num = length(img_settings.raw_data_name);
                %                 z_shift_times=img_settings.z_shift_times;
                shift_times    =img_settings.shift_times;
                Nnum      =psf_settings.Nnum;
                recon_n_slices=psf_settings.recon_n_slices;
                enhanced_n_slices=psf_settings.enhanced_n_slices;
                %                 bit_depths = img_structure.bit_depths;
                step3_wab=waitbar(0,'Loading Substacks and croppping');


                patch_size= app.PatchSizeEditField.Value;   %% update patchsize
                totol_number=app.n_parisEditField.Value;   %% update total numbers
                mean_threshold = app.ThreshholdEditField.Value; %% update crop threshold

                img_settings.save_hdf5=app.HDF5Button.Value;
                img_settings.patch_size=patch_size;

                %                 json_save_name= fullfile(congfig_file_path);
                delete(congfig_file_path);
                savejson('',img_settings,'filename',congfig_file_path,'Append',0);
                savejson('',psf_settings,'filename',congfig_file_path,'Append',1);

                %% get sub list

                sub_dir=sprintf('./Data/%s/S00_Multi_stage_data/Substacks/',prefix);
                sub_list=dir(sprintf('%s/*.tif',sub_dir));

                sLF_dir=sprintf('./Data/%s/S00_Multi_stage_data/DALF/',prefix);
                sLF_list=dir(sprintf('%s/*.tif',sLF_dir));

                cLF_dir=sprintf('./Data/%s/S00_Multi_stage_data/CleanLF/',prefix);
                cLF_list=dir(sprintf('%s/*.tif',cLF_dir));

                nLF_dir=sprintf('./Data/%s/S00_Multi_stage_data/NoisyLF/',prefix);
                nLF_list=dir(sprintf('%s/*.tif',nLF_dir));

                assert(length(sub_list)==length(sLF_list) && length(cLF_list)==length(nLF_list));
                sub_num=length(sub_list);

                temp_num=floor(totol_number/sub_num);
                n_paris_collect = ones(sub_num,1).*temp_num;
                last_numbers=totol_number-sum(n_paris_collect);
                n_paris_collect(end,1)=n_paris_collect(end,1)+last_numbers;
                srView_sride=patch_size*shift_times;
                Vol_stride= patch_size*sr_ratio;

                %% update save type
                hdf5_save_dir= sprintf('%s/training_data.h5',Training_data_dir);

                if app.HDF5Button.Value
                    if ~exist(hdf5_save_dir,'file')
                        h5create(hdf5_save_dir,"/nLF",[totol_number patch_size patch_size],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/cLF",[totol_number patch_size patch_size],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/sLF",[totol_number srView_sride srView_sride],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/sVol",[totol_number Vol_stride Vol_stride double(enhanced_n_slices)],'Datatype', 'single');
                    else
                        delete(hdf5_save_dir);
                        h5create(hdf5_save_dir,"/nLF",[totol_number patch_size patch_size],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/cLF",[totol_number patch_size patch_size],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/sLF",[totol_number srView_sride srView_sride],'Datatype', 'single');
                        h5create(hdf5_save_dir,"/sVol",[totol_number Vol_stride Vol_stride double(enhanced_n_slices)],'Datatype', 'single');
                    end
                    save_mat1= zeros([totol_number patch_size patch_size],'single');
                    save_mat2= zeros([totol_number patch_size patch_size],'single');
                    save_mat3= zeros([totol_number srView_sride srView_sride],'single');
                    save_mat4= zeros([totol_number Vol_stride Vol_stride enhanced_n_slices],'single');
                else
                    [~,~]=mkdir(sprintf('%s/nLF',Training_data_dir));
                    [~,~]=mkdir(sprintf('%s/cLF',Training_data_dir));
                    [~,~]=mkdir(sprintf('%s/sLF',Training_data_dir));
                    [~,~]=mkdir(sprintf('%s/sVol',Training_data_dir));
                end


                count_idx=0;
                for img_idx =1:sub_num
                    [SR_vol,local_bitdpeth]= imread3d(fullfile(sub_dir,sub_list(img_idx).name));
                    srLF= imread3d(fullfile(sLF_dir,sLF_list(img_idx).name));
                    centerLF= imread3d(fullfile(cLF_dir,cLF_list(img_idx).name));
                    noise_LF= imread3d(fullfile(nLF_dir,nLF_list(img_idx).name));
                    coordi_pool= get_coordi(SR_vol,Vol_stride,mean_threshold,Nnum,sr_ratio);
                    assert(~isempty(coordi_pool));
                    %% crop
                    n_pairs_per_image=n_paris_collect(img_idx,1);
                    if length(coordi_pool)<=n_pairs_per_image
                        random_idx1= randperm(length(coordi_pool));
                        random_idx2= randi([1,length(coordi_pool)],n_pairs_per_image-length(coordi_pool),1);
                        random_idx = [random_idx1';random_idx2];
                    else
                        random_idx= randperm(length(coordi_pool));
                        random_idx = random_idx(1:n_pairs_per_image);
                    end
                    select_coordi = coordi_pool(random_idx,:);

                    for coordi_idx=1:size(select_coordi,1)
                        %% roi

                        Vol_h_s=select_coordi(coordi_idx,1);
                        Vol_h_e=select_coordi(coordi_idx,2);

                        Vol_w_s = select_coordi(coordi_idx,3);
                        Vol_w_e = select_coordi(coordi_idx,4);


                        h_e= Vol_h_e/sr_ratio;
                        h_s= h_e-patch_size;

                        w_e= Vol_w_e/sr_ratio;
                        w_s= w_e-patch_size;

                        sv_h_e=h_e*shift_times;
                        sv_h_s= sv_h_e-srView_sride+1;

                        sv_w_e= w_e*shift_times;
                        sv_w_s= sv_w_e-srView_sride+1;


                        count_idx = count_idx+1;

                        c_x0= noise_LF(  h_s+1:h_e,w_s+1:w_e );
                        c_x1= centerLF(  h_s+1:h_e,w_s+1:w_e);
                        c_x2= srLF(  sv_h_s:sv_h_e,sv_w_s:sv_w_e);
                        c_x3= SR_vol(  Vol_h_s:Vol_h_e,Vol_w_s:Vol_w_e,:);


                        %% convet formats (LFM: to Macron; LFP: to ViewMap; Scan: to ViewMap)
                        c_x1= LFP2Map(c_x1,Nnum);
                        c_x2= LFP2Map(c_x2,Nnum);

                        if app.HDF5Button.Value
                            save_mat1(count_idx,:,:)=single(normalize(c_x0,0,100));
                            save_mat2(count_idx,:,:)=single(normalize(c_x1,0,100));
                            save_mat3(count_idx,:,:)=single(normalize(c_x2,0,100));
                            save_mat4(count_idx,:,:,:)=single(normalize(c_x3,0,100));
                        else
                            noise_lf_str=sprintf('%s/nLF/Idx_%05d.tif',Training_data_dir,count_idx);
                            clean_lf_str=sprintf('%s/cLF/Idx_%05d.tif',Training_data_dir,count_idx);
                            scan_lf_str=sprintf('%s/sLF/Idx_%05d.tif',Training_data_dir,count_idx);
                            sr_stacks_str=sprintf('%s/sVol/Idx_%05d.tif',Training_data_dir,count_idx);

                            write3d(c_x0,noise_lf_str,local_bitdpeth);
                            write3d(c_x1,clean_lf_str,local_bitdpeth);
                            write3d(c_x2,scan_lf_str,local_bitdpeth);
                            write3d(c_x3,sr_stacks_str,local_bitdpeth);

                        end
                        msg=sprintf('Cropping (Substacks idx:%d/%d, Patch idx:%d/%d)',img_idx,sub_num,coordi_idx,size(select_coordi,1));
                        waitbar(count_idx/totol_number,step3_wab,msg);
                    end
                end

                if app.HDF5Button.Value
                    msg =sprintf('Saving TrainingPairs');
                    waitbar(1,step3_wab,msg);
                    h5write(hdf5_save_dir,"/nLF",save_mat1);
                    h5write(hdf5_save_dir,"/cLF",save_mat2);
                    h5write(hdf5_save_dir,"/sLF",save_mat3);
                    h5write(hdf5_save_dir,"/sVol",save_mat4);
                    close(step3_wab);
                else
                    close(step3_wab);
                end

                %% save samples


            end


        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)

            app.StatusTextArea.Value = "~> ";
            app.Text_str = app.StatusTextArea.Value;
        end

        % Button pushed function: LoadingEnhancedData
        function LoadingEnhancedDataButtonPushed(app, event)

            global img_structure
            [raw_data_name,raw_data_path] = uigetfile('*.tiff;*.tif','Select Input Raw Data','MultiSelect','on','./example');
            if ~ischar (raw_data_path) || ~exist(raw_data_path,'dir')
                f = errordlg('File not found','File Error');
                pause(1);
                close(f);
                error('no file');
            end
            if ~iscell(raw_data_name)
                raw_data_name={raw_data_name};
            end
            app.EnhancedDataPath.Value=raw_data_path;
            img_structure.enhanced_data_path=raw_data_path;
            img_structure.enhanced_data_name=raw_data_name;
            outPut_str(app,sprintf('~>[Enhanced data detected] loading %d enhanced-images',length(raw_data_name)))
        end

        % Button pushed function: LoadingPSF
        function LoadingPSFButtonPushed(app, event)

            global psf_structure
            [psf_name,psf_path] = uigetfile('*.mat','Select Input PSF','MultiSelect','on','./source/psf');
            psf_file=fullfile(psf_path,psf_name);
            if ~exist(psf_file,'file')
                errordlg('No psf file (.mat) found')
            else
                app.psf_Text_pannel.Value=psf_name;
                psf_structure.path=psf_file;
                psf_structure.psf_customed_flag=true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataPreparationPanel = uipanel(app.UIFigure);
            app.DataPreparationPanel.Title = '1. Data Preparation';
            app.TabGroup = uitabgroup(app.DataPreparationPanel);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General ';
            app.DataSettingsPanel = uipanel(app.GeneralTab);
            app.DataSettingsPanel.Title = 'Data Settings';
            app.Button = uibutton(app.DataSettingsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'...'; ''};
            app.DataPathLabel = uilabel(app.DataSettingsPanel);
            app.DataPathLabel.Tooltip = {'The path of raw high-resolution stacks.'};
            app.DataPathLabel.Text = 'Data Path:';
            app.DataPathTextArea = uitextarea(app.DataSettingsPanel);
            app.DataPathTextArea.Value = {'./example/mito/'};
            app.DataLabelEditFieldLabel = uilabel(app.DataSettingsPanel);
            app.DataLabelEditFieldLabel.Tooltip = {'The name of to-be-saved dataset.'};
            app.DataLabelEditFieldLabel.Text = 'Data Label:';
            app.DataLabelEditField = uieditfield(app.DataSettingsPanel, 'text');
            app.DataLabelEditField.Value = 'mydata';
            app.DatasourceButtonGroup = uibuttongroup(app.DataSettingsPanel);
            app.DatasourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatasourceButtonGroupSelectionChanged, true);
            app.DatasourceButtonGroup.Tooltip = {'The name of provided example data'};
            app.DatasourceButtonGroup.Title = 'Data source';
            app.ExampleDataButton = uiradiobutton(app.DatasourceButtonGroup);
            app.ExampleDataButton.Text = 'Example Data';
            app.ExampleDataButton.Value = true;
            app.CustomizedButton = uiradiobutton(app.DatasourceButtonGroup);
            app.CustomizedButton.Text = 'Customized';
            app.PropertiesPanel = uipanel(app.DataSettingsPanel);
            app.PropertiesPanel.Title = 'Properties';
            app.PixelSizeLabel = uilabel(app.PropertiesPanel);
            app.PixelSizeLabel.Text = 'Pixel Size:';
            app.PixelSizeEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.PixelSizeEditField.Value = 0.054;
            app.mLabel = uilabel(app.PropertiesPanel);
            app.mLabel.Text = 'μm';
            app.mLabel_5 = uilabel(app.PropertiesPanel);
            app.mLabel_5.Text = 'μm';
            app.StepSizeEditFieldLabel = uilabel(app.PropertiesPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.StepSizeEditField.Value = 0.17;
            app.RunButton = uibutton(app.DataSettingsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SaveSubstacksCheckBox = uicheckbox(app.DataSettingsPanel);
            app.SaveSubstacksCheckBox.Tooltip = {'whether to save the uncropped paired data'};
            app.SaveSubstacksCheckBox.Text = 'SaveSubstacks';
            app.SaveSubstacksCheckBox.Value = true;
            app.SaveBitButtonGroup = uibuttongroup(app.DataSettingsPanel);
            app.SaveBitButtonGroup.Title = 'SaveBit';
            app.bit32 = uiradiobutton(app.SaveBitButtonGroup);
            app.bit32.Text = '32';
            app.bit16 = uiradiobutton(app.SaveBitButtonGroup);
            app.bit16.Text = '16';
            app.bit8 = uiradiobutton(app.SaveBitButtonGroup);
            app.bit8.Text = '8';
            app.bit8.Value = true;
            app.Panel = uipanel(app.GeneralTab);
            app.StatusTextArea = uitextarea(app.Panel);
            app.ClearButton_2 = uibutton(app.Panel, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.StatusPanelLabel = uilabel(app.Panel);
            app.StatusPanelLabel.Text = 'Status Panel';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.DegradationSimuPanel = uipanel(app.AdvancedTab);
            app.DegradationSimuPanel.Title = 'Degradation Simu';
            app.SBRRangeEditFieldLabel = uilabel(app.DegradationSimuPanel);
            app.SBRRangeEditFieldLabel.Text = 'SBR Range';
            app.SBRRangeEditField = uieditfield(app.DegradationSimuPanel, 'numeric');
            app.SBRRangeEditField.Value = 5;
            app.SBRmaxEditField = uieditfield(app.DegradationSimuPanel, 'numeric');
            app.SBRmaxEditField.Value = 20;
            app.Label_3 = uilabel(app.DegradationSimuPanel);
            app.Label_3.Text = '~';
            app.RawBackgroundin3DEditFieldLabel = uilabel(app.DegradationSimuPanel);
            app.RawBackgroundin3DEditFieldLabel.Text = 'RawBackground in 3D';
            app.RawBackgroundin3DEditField = uieditfield(app.DegradationSimuPanel, 'numeric');
            app.RollingBallRadiusEditFieldLabel = uilabel(app.DegradationSimuPanel);
            app.RollingBallRadiusEditFieldLabel.Tooltip = {' Rolling ball subtraction radius(0: disable)'};
            app.RollingBallRadiusEditFieldLabel.Text = 'RollingBall Radius';
            app.RollingBallRadiusEditField = uieditfield(app.DegradationSimuPanel, 'numeric');
            app.RollingBallRadiusEditField.Tooltip = {'The radius in "rollingball subtraction" (recommend 5~20)'};
            app.RollingBallRadiusEditField.Value = 10;
            app.BackgroundinLFMEditFieldLabel = uilabel(app.DegradationSimuPanel);
            app.BackgroundinLFMEditFieldLabel.Text = 'Background in LFM';
            app.BackgroundinLFMEditField = uieditfield(app.DegradationSimuPanel, 'numeric');
            app.BackgroundinLFMEditField.Value = 250;
            app.Label_9 = uilabel(app.DegradationSimuPanel);
            app.Label_9.Text = '~';
            app.GaussionNoiseLabel = uilabel(app.DegradationSimuPanel);
            app.GaussionNoiseLabel.Interpreter = 'latex';
            app.GaussionNoiseLabel.Text = ' $\sigma_{Gaussion}$';
            app.sigma_GaussionEditField = uieditfield(app.DegradationSimuPanel, 'numeric');
            app.sigma_GaussionEditField.Value = 2;
            app.GaussionNoise_maxEditField = uieditfield(app.DegradationSimuPanel, 'numeric');
            app.GaussionNoise_maxEditField.Value = 20;
            app.times105Label = uilabel(app.DegradationSimuPanel);
            app.times105Label.Interpreter = 'latex';
            app.times105Label.Text = '$\times10^{-5}$';
            app.lambda_PossionEditFieldLabel = uilabel(app.DegradationSimuPanel);
            app.lambda_PossionEditFieldLabel.Interpreter = 'latex';
            app.lambda_PossionEditFieldLabel.Visible = 'off';
            app.lambda_PossionEditFieldLabel.Text = ' $\lambda_{Possion}$';
            app.lambda_PossionEditField = uieditfield(app.DegradationSimuPanel, 'numeric');
            app.lambda_PossionEditField.Visible = 'off';
            app.lambda_PossionEditField.Value = 5000;
            app.CropSettingsPanel = uipanel(app.AdvancedTab);
            app.CropSettingsPanel.Title = 'CropSettings';
            app.PatchSizeEditFieldLabel = uilabel(app.CropSettingsPanel);
            app.PatchSizeEditFieldLabel.Text = 'PatchSize';
            app.PatchSizeEditField = uieditfield(app.CropSettingsPanel, 'numeric');
            app.PatchSizeEditField.Value = 360;
            app.n_parisEditFieldLabel = uilabel(app.CropSettingsPanel);
            app.n_parisEditFieldLabel.Text = 'Total pairs';
            app.n_parisEditField = uieditfield(app.CropSettingsPanel, 'numeric');
            app.n_parisEditField.Value = 720;
            app.ReCropSubstacksButton = uibutton(app.CropSettingsPanel, 'push');
            app.ReCropSubstacksButton.ButtonPushedFcn = createCallbackFcn(app, @ReCropSubstacksButtonPushed, true);
            app.ReCropSubstacksButton.Text = 'Re-Crop Substacks';
            app.TraingPairSaveButtonGroup = uibuttongroup(app.CropSettingsPanel);
            app.TraingPairSaveButtonGroup.Title = 'TraingPairSave';
            app.HDF5Button = uiradiobutton(app.TraingPairSaveButtonGroup);
            app.HDF5Button.Text = 'HDF5';
            app.TiFButton = uiradiobutton(app.TraingPairSaveButtonGroup);
            app.TiFButton.Text = 'TiF';
            app.TiFButton.Value = true;
            app.ThreshholdEditFieldLabel = uilabel(app.CropSettingsPanel);
            app.ThreshholdEditFieldLabel.Text = 'Threshhold';
            app.ThreshholdEditField = uieditfield(app.CropSettingsPanel, 'numeric');
            app.ThreshholdEditField.Value = 0.5;
            app.PreEnhancedDataPanel = uipanel(app.AdvancedTab);
            app.PreEnhancedDataPanel.Title = 'Pre-Enhanced Data';
            app.EnhancedDataPath = uitextarea(app.PreEnhancedDataPanel);
            app.EnhancedDataPath.Value = {'None'};
            app.LoadingEnhancedData = uibutton(app.PreEnhancedDataPanel, 'push');
            app.LoadingEnhancedData.ButtonPushedFcn = createCallbackFcn(app, @LoadingEnhancedDataButtonPushed, true);
            app.LoadingEnhancedData.Text = {'...'; ''};
            app.DatapathLabel = uilabel(app.PreEnhancedDataPanel);
            app.DatapathLabel.Text = 'Data path';
            app.ProjectionSettingsPanel = uipanel(app.AdvancedTab);
            app.ProjectionSettingsPanel.Title = 'Projection Settings';
            app.ProjectionGPUEditFieldLabel = uilabel(app.ProjectionSettingsPanel);
            app.ProjectionGPUEditFieldLabel.Text = 'Projection GPU:';
            app.ProjectionGPUEditField = uieditfield(app.ProjectionSettingsPanel, 'numeric');
            app.ButtonGroup = uibuttongroup(app.ProjectionSettingsPanel);
            app.scan9 = uiradiobutton(app.ButtonGroup);
            app.scan9.Text = '3×3';
            app.scan25 = uiradiobutton(app.ButtonGroup);
            app.scan25.Text = '5×5';
            app.scan25.Value = true;
            app.SASTimesEditFieldLabel = uilabel(app.ButtonGroup);
            app.SASTimesEditFieldLabel.Text = 'SAS Number:';
            app.CustomizedPSFPanel = uipanel(app.AdvancedTab);
            app.CustomizedPSFPanel.Title = 'Customized PSF';
            app.PSFpathLabel = uilabel(app.CustomizedPSFPanel);
            app.PSFpathLabel.Text = 'PSF path';
            app.psf_Text_pannel = uitextarea(app.CustomizedPSFPanel);
            app.psf_Text_pannel.Value = {'"./source/psf/PSFmatrix_M60NA1.1MLPitch100fml2800from-5.1to3.4zspacing0.85Nnum15lambda525n1.4.mat"'};
            app.LoadingPSF = uibutton(app.CustomizedPSFPanel, 'push');
            app.LoadingPSF.ButtonPushedFcn = createCallbackFcn(app, @LoadingPSFButtonPushed, true);
            app.LoadingPSF.Text = {'...'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataPreparation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end